classdef searchAlgorithm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SearchAlgorithmDropDownLabel  matlab.ui.control.Label
        SearchAlgorithmDropDown       matlab.ui.control.DropDown
        ResolutionSpinnerLabel        matlab.ui.control.Label
        ResolutionSpinner             matlab.ui.control.Spinner
        MapDropDownLabel              matlab.ui.control.Label
        MapDropDown                   matlab.ui.control.DropDown
        StartPositionPanel            matlab.ui.container.Panel
        XLabel_Start                  matlab.ui.control.Label
        XEditField_Start              matlab.ui.control.NumericEditField
        YLabel_Start                  matlab.ui.control.Label
        YEditField_Start              matlab.ui.control.NumericEditField
        GoalPositionPanel             matlab.ui.container.Panel
        XLabel_Goal                   matlab.ui.control.Label
        XEditField_Goal               matlab.ui.control.NumericEditField
        YLabel_Goal                   matlab.ui.control.Label
        YEditField_Goal               matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        StartButton                   matlab.ui.control.Button
        ActionSpaceDropDownLabel      matlab.ui.control.Label
        ActionSpaceDropDown           matlab.ui.control.DropDown
        DistanceDropDownLabel         matlab.ui.control.Label
        DistanceDropDown              matlab.ui.control.DropDown
        CommentsTextAreaLabel         matlab.ui.control.Label
        RobotTypeDropDownLabel        matlab.ui.control.Label
        RobotTypeDropDown             matlab.ui.control.DropDown
        RobotRadiusSpinnerLabel       matlab.ui.control.Label
        RobotRadiusSpinner            matlab.ui.control.Spinner
        ClearanceSpinnerLabel         matlab.ui.control.Label
        ClearanceSpinner              matlab.ui.control.Spinner
        CommentsTextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        searchAlgo = 'BFS'; % {'BFS', 'DFS', 'Dijkstra', 'A*', 'Weighted A*'}
        actions = [0 1; 1 0; 0 -1; -1 0]; % {[0 1; 1 0; 0 -1; -1 0], [0 1; 1 1; 1 0; 1 -1; 0 -1; -1 -1; -1 0; -1 1]}
        distance = 'Manhattan'; % {'Manhattan', 'Euclidean'}
        startPos = [0 0]; % Set using the respective dialog box
        goalPos = [250 150]; % Set using the respective dialog box
        mapName = 'Map 2018'; % {'Map 2018', 'Map 2019'}
        res = 5; % Set using the respective dialog box
        radius = 0; % Set when robot is rigid using the respective dialog box
        clearance = 0; % Set when robot is rigid using the respective dialog box
    end
%     2^x*3^y
    methods (Access = private)
        % Plot the actual map
        function plotMap(app)
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            if strcmp(app.mapName, 'Map 2018')
                % Real Obstacle 1 - Rectangle
                rectangle(app.UIAxes, 'Position', [55 67.5 50 45], 'FaceColor', 'k');
                % Real Obstacle 2 - Concave Shape
                shape = [120 55; 158 51; 165 89; 188 51; 168 14; 145 14];
                patch(app.UIAxes, shape(:,1), shape(:,2), 'k');
                % Real Obstacle 3 - Circle
                rectangle(app.UIAxes, 'Position', [165 105 30 30], 'Curvature', [1 1], 'FaceColor', 'k');
            elseif strcmp(app.mapName, 'Map 2019')
                % Real Obstacle 1 - Rectangle
                rectangle(app.UIAxes, 'Position', [50 67.5 50 45], 'FaceColor', 'k');
                % Real Obstacle 2 - Concave Shape
                shape = [125 56; 163 52; 170 90; 193 52; 173 15; 150 15];
                patch(app.UIAxes, shape(:,1), shape(:,2), 'k');
                % Real Obstacle 3 - Circle
                rectangle(app.UIAxes, 'Position', [175 115 30 30], 'Curvature', [1 1], 'FaceColor', 'k');
                % Real Obstacle 4 - Ellipse
                rectangle(app.UIAxes, 'Position', [125 114 30 12], 'Curvature', [1 1], 'FaceColor', 'k');
            end
            hold(app.UIAxes, 'off');
        end


        function path = astar_proj2(app, res, start, goal, createVideo)
        
            % Check if the resolution is correct
            if isempty(res)
                disp('No resolution defined');
                path = [];
                return;
            elseif (rem(250,res)~=0)||(rem(150,res)~=0)
                disp('The resolution is not correct. Grid cannot be formed.');
                path = [];
                return;
            end
            
            % Create a grid to identify obstacles
            map = cell(7,1);
            map{1} = (res/2):res:250;                                               % x-values of centre of grid
            map{2} = (res/2):res:150;                                               % y-values of centre of grid
            obs1 = [55 112.5; 105 112.5; 105 67.5; 55 67.5];                        % points of interest for obstacle 1
            map{3} = [polyfit(obs1(1:2,1),obs1(1:2,2),1);polyfit(obs1(2:3,1),obs1(2:3,2),1);...
                      polyfit(obs1(3:4,1),obs1(3:4,2),1);polyfit(obs1([4,1],1),obs1([4,1],2),1)];
            obs2 = [120 55; 158 51; 165 89; 188 51; 168 14; 145 14];                % points of interest for obstacle 2
            map{4} = [polyfit(obs2(1:2,1),obs2(1:2,2),1);polyfit(obs2(2:3,1),obs2(2:3,2),1);...
                      polyfit(obs2(3:4,1),obs2(3:4,2),1);polyfit(obs2(4:5,1),obs2(4:5,2),1);...
                      polyfit(obs2(5:6,1),obs2(5:6,2),1);polyfit(obs2([6,1],1),obs2([6,1],2),1);...
                      polyfit(obs2([1,3],1),obs2([1,3],2),1)];
            map{5} = [180 120 15];                                                  % points of interest for obstacle 3
            map{6} = res;                                                           % resolution
            map{7} = false;                                                         % state of points to be tested for collision
            
            % Plot map
            figure('units','normalized','outerposition',[0 0 1 1])
            axis([0 250 0 150]);
            if res > min([map{1}(end) map{2}(end)])*0.02
                set(gca,'xtick',0:res:250,'ytick',0:res:150)
                grid on
                
                hold on
                for i=1:length(map{1})
                    for j=1:length(map{2})
                        % Obstacle 1
                        if (map{1}(i)<112.5)&&((map{2}(j)<=map{3}(1,1)*map{1}(i)+map{3}(1,2))&&...
                                               (map{1}(i)<=(map{2}(j)-map{3}(2,2))/map{3}(2,1))&&...
                                               (map{2}(j)>=map{3}(3,1)*map{1}(i)+map{3}(3,2))&&...
                                               (map{1}(i)>=(map{2}(j)-map{3}(4,2))/map{3}(4,1)))
                            rectangle('Position',[map{1}(i)-(res/2) map{2}(j)-(res/2) res res],'FaceColor','k');
                        % Obstacle 2
                        elseif (map{2}(j)<97.5)&&(((map{1}(i)<(map{2}(j)-map{4}(3,2))/map{4}(3,1))||...
                                                   (map{1}(i)-(res/2)<(map{2}(j)-(res/2)-map{4}(3,2))/map{4}(3,1)))&&...
                                                  ((map{1}(i)<(map{2}(j)-map{4}(4,2))/map{4}(4,1))||...
                                                   (map{1}(i)-(res/2)<(map{2}(j)+(res/2)-map{4}(4,2))/map{4}(4,1)))&&...
                                                  ((map{2}(j)>map{4}(5,1)*map{1}(i)+map{4}(5,2))||...
                                                   (map{2}(j)+(res/2)>map{4}(5,1)*map{1}(i)+map{4}(5,2)))&&...
                                                  ((map{1}(i)>(map{2}(j)-map{4}(6,2))/map{4}(6,1))||...
                                                   (map{1}(i)+(res/2)>(map{2}(j)+(res/2)-map{4}(6,2))/map{4}(6,1)))&&...
                                                  ((map{2}(j)<map{4}(7,1)*map{1}(i)+map{4}(7,2))||...
                                                   (map{2}(j)-(res/2)<map{4}(7,1)*map{1}(i)+map{4}(7,2)))&&...
                                                  ((map{2}(j)<map{4}(1,1)*map{1}(i)+map{4}(1,2))||...
                                                   (map{2}(j)-(res/2)<map{4}(1,1)*map{1}(i)+map{4}(1,2))||...
                                                   (map{1}(i)>(map{2}(j)-map{4}(2,2))/map{4}(2,1))||...
                                                   (map{1}(i)+(res/2)>(map{2}(j)-(res/2)-map{4}(2,2))/map{4}(2,1))))
                            rectangle('Position',[map{1}(i)-(res/2) map{2}(j)-(res/2) res res],'FaceColor','k');
                        % Obstacle 3
                        elseif (((map{1}(i)-map{5}(1))^2 + (map{2}(j)-map{5}(2))^2)<(map{5}(3)^2))||...
                               (((map{1}(i)+(res/2)-map{5}(1))^2 + (map{2}(j)+(res/2)-map{5}(2))^2)<(map{5}(3)^2))||...
                               (((map{1}(i)+(res/2)-map{5}(1))^2 + (map{2}(j)-(res/2)-map{5}(2))^2)<(map{5}(3)^2))||...
                               (((map{1}(i)-(res/2)-map{5}(1))^2 + (map{2}(j)-(res/2)-map{5}(2))^2)<(map{5}(3)^2))||...
                               (((map{1}(i)-(res/2)-map{5}(1))^2 + (map{2}(j)+(res/2)-map{5}(2))^2)<(map{5}(3)^2))
                            rectangle('Position',[map{1}(i)-(res/2) map{2}(j)-(res/2) res res],'FaceColor','k');
                        end
                    end
                end
            else
                hold on
                % Real Obstacle 1
                patch(obs1(:,1),obs1(:,2),'k')
                % Real Obstacle 2
                patch(obs2(:,1),obs2(:,2),'k')
                % Real Obstacle 3
                t = linspace(0,2*pi,1000);
                patch(map{5}(1)+map{5}(3)*cos(t),map{5}(2)+map{5}(3)*sin(t),'k')
            end
            
            % If start is empty, ask the user to set start point
            if isempty(start)
                title('No start point was mentioned. Select a start point in the grid.');
                start = ginput(1);
                title('');
            end
            % Convert the given start point to grid coordinates
            [C_start,start_pos] = collide_proj2(map,start);
            if C_start == 1
                uiwait(msgbox('The start point does not lie in the free space of the grid.'));
                close all
                path = [];
                return;
            end
            rectangle('Position',[start_pos(1)-(res/2) start_pos(2)-(res/2) res res],'FaceColor','r');
            
            % If goal is empty, ask the user to set goal point
            if isempty(goal)
                title('No goal point was mentioned. Select a goal point in the grid.');
                goal = ginput(1);
                title('');
            end
            % Convert the given goal point to grid coordinates
            [C_goal,goal_pos] = collide_proj2(map,goal);
            if C_goal == 1
                uiwait(msgbox('The goal point does not lie in the free space of the grid.'));
                close all
                path = [];
                return;
            end
            rectangle('Position',[goal_pos(1)-(res/2) goal_pos(2)-(res/2) res res],'FaceColor','b');
            
            % Create a video
            if createVideo
                vidObj = VideoWriter(['../output/astar_proj2 demo_run (res = ' num2str(res) ').mp4'],'MPEG-4');
                vidObj.FrameRate = 30;
                open(vidObj);
                writeVideo(vidObj, getframe(gcf));
            end
            
            % Set the points to be in grid coords
            map{7} = true;
            title('Running A* Algorithm')
            pause(0.1)
            
            % Set the initial node
            init_node.pos = start_pos;
            init_node.gcost = 0;
            init_node.hcost = norm(start_pos-goal_pos);
            init_node.fcost = init_node.gcost + init_node.hcost;
            init_node.parent = struct;
            
            % Start an OPEN list
            OPEN = containers.Map(init_node.fcost,init_node);
            k = init_node.fcost;
            
            % Define all possible actions
            actions = [res, 0; res, res; 0, res; -res, res; -res, 0; -res, -res; 0, -res; res, -res];
            
            % Start a CLOSED list
            CLOSED = zeros(length(map{1}),length(map{2}));
            
            % Start a path list
            path = goal_pos;
            tol = res/1e4;
            
            % Search algorithm
            while ~isempty(OPEN)
                if length(OPEN(min(k)))~=1
                    OPEN_temp = OPEN(min(k));
                    current_node = OPEN_temp(1);
                    OPEN_temp(1:end-1) = OPEN_temp(2:end);
                    OPEN_temp(end) = [];
                    OPEN(min(k)) = OPEN_temp;
                else
                    current_node = OPEN(min(k));
                    remove(OPEN,min(k));
                    k = k(abs(k-min(k))>tol);
                end
                
                if all(abs(current_node.pos - goal_pos)<tol)
                    parent_node = current_node.parent;
                    while any(abs(parent_node.pos - start_pos)>tol)
                        path = [path; parent_node.pos];
                        parent_node = parent_node.parent;
                    end
                    break;
                end
                
                x = find(abs(map{1}-current_node.pos(1))<tol,1);
                y = find(abs(map{2}-current_node.pos(2))<tol,1);
                if CLOSED(x,y) == 1
                    continue;
                end
                CLOSED(x,y) = 1;
                % expand
                for i=1:length(actions)
                    neighbor_node.pos = current_node.pos + actions(i,:);
                    % check if it intersects with an obstacle (also checks if it
                    % lies inside the boundary of the map)
                    if collide_proj2(map,neighbor_node.pos)
                        continue;
                    end
                    % check if closed already
                    if CLOSED(abs(map{1}-neighbor_node.pos(1))<tol,abs(map{2}-neighbor_node.pos(2))<tol)
                        continue;
                    end
                    neighbor_node.gcost = current_node.gcost + norm(neighbor_node.pos-current_node.pos);
                    neighbor_node.hcost = norm(neighbor_node.pos - goal_pos);
                    neighbor_node.fcost = neighbor_node.gcost + neighbor_node.hcost;
                    neighbor_node.parent = current_node;
                    if isKey(OPEN,neighbor_node.fcost)
                        OPEN(neighbor_node.fcost) = [OPEN(neighbor_node.fcost);neighbor_node];
                    else
                        OPEN(neighbor_node.fcost) = neighbor_node;
                        k(end+1) = neighbor_node.fcost;
                    end
                    if any(abs(neighbor_node.pos - goal_pos)>tol)
                        rectangle('Position',[neighbor_node.pos(1)-(res/2) neighbor_node.pos(2)-(res/2) res res],'FaceColor','y');
                        if createVideo
                            writeVideo(vidObj, getframe(gcf));
                        end
                        pause(0.001)
                    end
                end
            end
            path = [path; start_pos];
            path = flipud(path);
            
            % Plot path
            title('Path Found')
            for i=2:(size(path,1)-1)
                rectangle('Position',[path(i,1)-(res/2) path(i,2)-(res/2) res res],'FaceColor','c');
                if createVideo
                    writeVideo(vidObj, getframe(gcf));
                end
                pause(0.01)
            end
            plot(path(:,1),path(:,2))
            hold off
            
            if createVideo
                for i = 1:5*vidObj.FrameRate
                    writeVideo(vidObj, getframe(gcf));
                end
                close(vidObj);
            end
            
            % Save the figure
            saveas(gcf,['../output/astar_path_proj2 (res = ' num2str(res) ').jpg']);
        
        end
%         function results = func(app)
%             app.myAx = subplot(2,1,1,'Parent',app.Panel);  % create plot
% app.myAx.ButtonDownFcn = createCallbackFcn(app, @app.getMousePosition, true);
% function getMousePosition(app,source,event)
%     switch event.EventName
%         case 'Hit'
%             % Properties: Btn/IntersectionPoint/Src/Evnt
%             posData = event.IntersectionPoint; % to be used for calc
%         case 'MouseData'
%             % Properties: Src/EvntName
%     end
% end
%         end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Place the GUI in the center of the screen
            screenSize = get(0,'ScreenSize');
            edge = (screenSize(3:4) - app.UIFigure.Position(3:4))/2;
            edge(edge < 0) = 0;
            app.UIFigure.Position = [edge app.UIFigure.Position(3:4)];
            drawnow;
            
            % Plot the map
            app.UIAxes.XTick = 0:app.res:250;
            app.UIAxes.YTick = 0:app.res:150;
            xtickangle(app.UIAxes, 90)
            plotMap(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: SearchAlgorithmDropDown
        function SearchAlgorithmDropDownValueChanged(app, event)
            app.searchAlgo = app.SearchAlgorithmDropDown.Value;
        end

        % Value changed function: ActionSpaceDropDown
        function ActionSpaceDropDownValueChanged(app, event)
            selectedObject = app.ActionSpaceDropDown.Value;
            if strcmp(selectedObject, '4-connected')
                app.actions = [0 1; 1 0; 0 -1; -1 0];
            elseif strcmp(selectedObject, '8-connected')
                app.actions = [0 1; 1 1; 1 0; 1 -1; 0 -1; -1 -1; -1 0; -1 1];
            end
        end

        % Value changed function: DistanceDropDown
        function DistanceDropDownValueChanged(app, event)
            app.distance = app.DistanceDropDown.Value;
        end

        % Value changed function: XEditField_Start
        function XEditField_StartValueChanged(app, event)
            app.startPos(1) = app.XEditField_Start.Value;
        end

        % Value changed function: YEditField_Start
        function YEditField_StartValueChanged(app, event)
            app.startPos(2) = app.YEditField_Start.Value;
        end

        % Value changed function: XEditField_Goal
        function XEditField_GoalValueChanged(app, event)
            app.goalPos(1) = app.XEditField_Goal.Value;
        end

        % Value changed function: YEditField_Goal
        function YEditField_GoalValueChanged(app, event)
            app.goalPos(2) = app.YEditField_Goal.Value;
        end

        % Value changed function: MapDropDown
        function MapDropDownValueChanged(app, event)
            app.mapName = app.MapDropDown.Value;
            plotMap(app);
        end

        % Value changed function: ResolutionSpinner
        function ResolutionSpinnerValueChanged(app, event)
            value = app.ResolutionSpinner.Value;
            
            % Check if the value is still changing
            pause on
            pause(1)
            while value ~= app.ResolutionSpinner.Value
                value = app.ResolutionSpinner.Value;
                pause(1)
            end
            
            % Check if the resolution is feasible
            if (rem(250, value) ~= 0) || (rem(150, value) ~= 0)
                app.ResolutionSpinner.Value = app.res;
                app.CommentsTextArea.Value = {'Uniform Grid cannot be formed for the given resolution.'};
            else
                app.res = value;
            end
            pause(2)
            app.CommentsTextArea.Value = {'Select all the parameters and then start the search algorithm.'};
            pause off
            
            % Update the grid size
            app.UIAxes.XTick = 0:app.res:250;
            app.UIAxes.YTick = 0:app.res:150;
        end

        % Value changed function: RobotTypeDropDown
        function RobotTypeDropDownValueChanged(app, event)
            selectedObject = app.RobotTypeDropDown.Value;
            if strcmp(selectedObject, 'Point')
                app.RobotRadiusSpinner.Visible = 'off';
                app.RobotRadiusSpinnerLabel.Visible = 'off';
                app.ClearanceSpinner.Visible = 'off';
                app.ClearanceSpinnerLabel.Visible = 'off';
                app.radius = 0;
                app.clearance = 0;
            elseif strcmp(selectedObject, 'Rigid')
                app.RobotRadiusSpinner.Visible = 'on';
                app.RobotRadiusSpinnerLabel.Visible = 'on';
                app.ClearanceSpinner.Visible = 'on';
                app.ClearanceSpinnerLabel.Visible = 'on';
                app.radius = app.RobotRadiusSpinner.Value;
                app.clearance = app.ClearanceSpinner.Value;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SearchAlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.SearchAlgorithmDropDownLabel.Text = 'Search Algorithm';
            app.SearchAlgorithmDropDown = uidropdown(app.UIFigure);
            app.SearchAlgorithmDropDown.Items = {'BFS', 'DFS', 'Dijkstra', 'A*', 'Weighted A*'};
            app.SearchAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @SearchAlgorithmDropDownValueChanged, true);
            app.SearchAlgorithmDropDown.Value = 'BFS';
            app.ResolutionSpinnerLabel = uilabel(app.UIFigure);
            app.ResolutionSpinnerLabel.Text = 'Resolution';
            app.ResolutionSpinner = uispinner(app.UIFigure);
            app.ResolutionSpinner.Limits = [0 Inf];
            app.ResolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @ResolutionSpinnerValueChanged, true);
            app.ResolutionSpinner.Value = 5;
            app.MapDropDownLabel = uilabel(app.UIFigure);
            app.MapDropDownLabel.Text = 'Map';
            app.MapDropDown = uidropdown(app.UIFigure);
            app.MapDropDown.Items = {'Map 2018', 'Map 2019'};
            app.MapDropDown.ValueChangedFcn = createCallbackFcn(app, @MapDropDownValueChanged, true);
            app.MapDropDown.Value = 'Map 2018';
            app.StartPositionPanel = uipanel(app.UIFigure);
            app.StartPositionPanel.Title = 'Start Position';
            app.XLabel_Start = uilabel(app.StartPositionPanel);
            app.XLabel_Start.Text = 'X :';
            app.XEditField_Start = uieditfield(app.StartPositionPanel, 'numeric');
            app.XEditField_Start.Limits = [0 250];
            app.XEditField_Start.ValueChangedFcn = createCallbackFcn(app, @XEditField_StartValueChanged, true);
            app.YLabel_Start = uilabel(app.StartPositionPanel);
            app.YLabel_Start.Text = 'Y :';
            app.YEditField_Start = uieditfield(app.StartPositionPanel, 'numeric');
            app.YEditField_Start.Limits = [0 150];
            app.YEditField_Start.ValueChangedFcn = createCallbackFcn(app, @YEditField_StartValueChanged, true);
            app.GoalPositionPanel = uipanel(app.UIFigure);
            app.GoalPositionPanel.Title = 'Goal Position';
            app.XLabel_Goal = uilabel(app.GoalPositionPanel);
            app.XLabel_Goal.Text = 'X :';
            app.XEditField_Goal = uieditfield(app.GoalPositionPanel, 'numeric');
            app.XEditField_Goal.Limits = [0 250];
            app.XEditField_Goal.ValueChangedFcn = createCallbackFcn(app, @XEditField_GoalValueChanged, true);
            app.XEditField_Goal.Value = 250;
            app.YLabel_Goal = uilabel(app.GoalPositionPanel);
            app.YLabel_Goal.Text = 'Y :';
            app.YEditField_Goal = uieditfield(app.GoalPositionPanel, 'numeric');
            app.YEditField_Goal.Limits = [0 150];
            app.YEditField_Goal.ValueChangedFcn = createCallbackFcn(app, @YEditField_GoalValueChanged, true);
            app.YEditField_Goal.Value = 150;
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X (in mm)')
            ylabel(app.UIAxes, 'Y (in mm)')
            app.UIAxes.XLim = [0 250];
            app.UIAxes.YLim = [0 150];
            app.UIAxes.XTick = [0 50 100 150 200 250];
            app.UIAxes.YTick = [0 50 100 150];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.Text = 'Start';
            app.ActionSpaceDropDownLabel = uilabel(app.UIFigure);
            app.ActionSpaceDropDownLabel.Text = 'Action Space';
            app.ActionSpaceDropDown = uidropdown(app.UIFigure);
            app.ActionSpaceDropDown.Items = {'4-connected', '8-connected'};
            app.ActionSpaceDropDown.ValueChangedFcn = createCallbackFcn(app, @ActionSpaceDropDownValueChanged, true);
            app.ActionSpaceDropDown.Value = '4-connected';
            app.DistanceDropDownLabel = uilabel(app.UIFigure);
            app.DistanceDropDownLabel.Text = 'Distance';
            app.DistanceDropDown = uidropdown(app.UIFigure);
            app.DistanceDropDown.Items = {'Manhattan', 'Euclidean'};
            app.DistanceDropDown.ValueChangedFcn = createCallbackFcn(app, @DistanceDropDownValueChanged, true);
            app.DistanceDropDown.Value = 'Manhattan';
            app.CommentsTextAreaLabel = uilabel(app.UIFigure);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.UIFigure);
            app.CommentsTextArea.Editable = 'off';
            app.CommentsTextArea.Value = {'Select all the parameters and then start the search algorithm.'};
            app.RobotTypeDropDownLabel = uilabel(app.UIFigure);
            app.RobotTypeDropDownLabel.Text = 'Robot Type';
            app.RobotTypeDropDown = uidropdown(app.UIFigure);
            app.RobotTypeDropDown.Items = {'Point', 'Rigid'};
            app.RobotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotTypeDropDownValueChanged, true);
            app.RobotTypeDropDown.Value = 'Point';
            app.RobotRadiusSpinnerLabel = uilabel(app.UIFigure);
            app.RobotRadiusSpinnerLabel.Visible = 'off';
            app.RobotRadiusSpinnerLabel.Text = 'Robot Radius';
            app.RobotRadiusSpinner = uispinner(app.UIFigure);
            app.RobotRadiusSpinner.Limits = [0 Inf];
            app.RobotRadiusSpinner.Visible = 'off';
            app.RobotRadiusSpinner.Value = 5;
            app.ClearanceSpinnerLabel = uilabel(app.UIFigure);
            app.ClearanceSpinnerLabel.Visible = 'off';
            app.ClearanceSpinnerLabel.Text = 'Clearance';
            app.ClearanceSpinner = uispinner(app.UIFigure);
            app.ClearanceSpinner.Limits = [0 Inf];
            app.ClearanceSpinner.Visible = 'off';
            app.ClearanceSpinner.Value = 2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = searchAlgorithm

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end